classdef DataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAnalysisUIFigure          matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FindPeaksTab                  matlab.ui.container.Tab
        OpenButton                    matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        SaveDataButton                matlab.ui.control.Button
        clearvariablesButton          matlab.ui.control.Button
        CommentTextAreaLabel          matlab.ui.control.Label
        CommentTextArea               matlab.ui.control.TextArea
        FilepathTextAreaLabel         matlab.ui.control.Label
        FilepathTextArea              matlab.ui.control.TextArea
        PeakAnalysisPanel             matlab.ui.container.Panel
        FilterpropertiesPanel         matlab.ui.container.Panel
        FiltertypeDropDownLabel       matlab.ui.control.Label
        FiltertypeDropDown            matlab.ui.control.DropDown
        WindowsizeSpinnerLabel        matlab.ui.control.Label
        WindowsizeSpinner             matlab.ui.control.Spinner
        FilterdataButton              matlab.ui.control.Button
        Plotfiltered                  matlab.ui.control.Switch
        PlotfilteredLabel             matlab.ui.control.Label
        Plot1PeaksPanel               matlab.ui.container.Panel
        sdevLabel                     matlab.ui.control.Label
        Plot1stdDisp                  matlab.ui.control.NumericEditField
        SlopeThresholdLabel_2         matlab.ui.control.Label
        Plot1Slopethreshold           matlab.ui.control.NumericEditField
        GroupwidthLabel               matlab.ui.control.Label
        Plot1GroupWidth               matlab.ui.control.NumericEditField
        SmoothwidthLabel_2            matlab.ui.control.Label
        Plot1SmoothWidth              matlab.ui.control.NumericEditField
        SmoothtypeSpinner_2Label      matlab.ui.control.Label
        Plot1Smoothtype               matlab.ui.control.Spinner
        AmplitudeThresholdLabel_2     matlab.ui.control.Label
        Plot1Ampthreshold             matlab.ui.control.Spinner
        Plot2PeaksPanel               matlab.ui.container.Panel
        sdevLabel_2                   matlab.ui.control.Label
        Plot2stdDisp                  matlab.ui.control.NumericEditField
        SlopeThresholdLabel           matlab.ui.control.Label
        Plot2Slopethreshold           matlab.ui.control.NumericEditField
        SmoothwidthLabel              matlab.ui.control.Label
        Plot2SmoothWidth              matlab.ui.control.NumericEditField
        SmoothtypeSpinnerLabel        matlab.ui.control.Label
        Plot2Smoothtype               matlab.ui.control.Spinner
        GroupwidthLabel_2             matlab.ui.control.Label
        Plot2GroupWidth               matlab.ui.control.NumericEditField
        AmplitudeThresholdLabel_3     matlab.ui.control.Label
        Plot2Ampthreshold             matlab.ui.control.Spinner
        ReGrouppeaksButton            matlab.ui.control.Button
        GrouppeakswithinLabel         matlab.ui.control.Label
        DurationThresholdSpinner      matlab.ui.control.Spinner
        msLabel                       matlab.ui.control.Label
        findpeaksButton               matlab.ui.control.Button
        AddPlot1PeakButton            matlab.ui.control.Button
        AddPlot2PeakButton            matlab.ui.control.Button
        NegativepeaksSwitchLabel      matlab.ui.control.Label
        NegativepeaksSwitch           matlab.ui.control.Switch
        AddspecialPeaksButton         matlab.ui.control.Button
        PeakDetectionMethodDropDownLabel  matlab.ui.control.Label
        PeakDetectionMethodDropDown   matlab.ui.control.DropDown
        plot1                         matlab.ui.control.UIAxes
        plot2                         matlab.ui.control.UIAxes
        PeaksPlot                     matlab.ui.control.UIAxes
        SaveplotsButton               matlab.ui.control.Button
        PlotdetrendeddataSwitchLabel  matlab.ui.control.Label
        PlotdetrendeddataSwitch       matlab.ui.control.Switch
        StepDetectionPanel            matlab.ui.container.Panel
        AddStepPlot1Button            matlab.ui.control.Button
        startPointSpinnerLabel        matlab.ui.control.Label
        startPointSpinner             matlab.ui.control.Spinner
        rightIntervalSpinnerLabel     matlab.ui.control.Label
        rightIntervalSpinner          matlab.ui.control.Spinner
        leftIntervalSpinnerLabel      matlab.ui.control.Label
        leftIntervalSpinner           matlab.ui.control.Spinner
        leftOffsetSpinnerLabel        matlab.ui.control.Label
        leftOffsetSpinner             matlab.ui.control.Spinner
        rightOffsetSpinnerLabel       matlab.ui.control.Label
        rightOffsetSpinner            matlab.ui.control.Spinner
        Plot1heightfmEditFieldLabel   matlab.ui.control.Label
        plot1heightfmEditField        matlab.ui.control.NumericEditField
        Plot2heightfmEditFieldLabel   matlab.ui.control.Label
        plot2heightfmEditField        matlab.ui.control.NumericEditField
        AddStepPlot2Button            matlab.ui.control.Button
        WinsizeSpinnerLabel           matlab.ui.control.Label
        WinsizeSpinner                matlab.ui.control.Spinner
        XStartSpinnerLabel            matlab.ui.control.Label
        XStartSpinner                 matlab.ui.control.Spinner
        XEndSpinnerLabel              matlab.ui.control.Label
        XEndSpinner                   matlab.ui.control.Spinner
        WidthPlot                     matlab.ui.control.UIAxes
        stepheight                    matlab.ui.control.UIAxes
        PlotoptionsPanel              matlab.ui.container.Panel
        panPlots                      matlab.ui.control.Button
        resetPlots                    matlab.ui.control.Button
        zoomPlots                     matlab.ui.control.Button
        getPointsPlots                matlab.ui.control.Button
        RedetrendButton               matlab.ui.control.Button
        ChannelDropDownLabel          matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDown_2Label        matlab.ui.control.Label
        ChannelDropDown_2             matlab.ui.control.DropDown
        stepheight_2                  matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        StartTimeSpinnerLabel         matlab.ui.control.Label
        StartTimeSpinner              matlab.ui.control.Spinner
        PlotdurationSpinnerLabel      matlab.ui.control.Label
        PlotdurationSpinner           matlab.ui.control.Spinner
        StdoverpointsSpinnerLabel     matlab.ui.control.Label
        nStdSpinner                   matlab.ui.control.Spinner
        ModenumberDropDownLabel       matlab.ui.control.Label
        ModenumberDropDown            matlab.ui.control.DropDown
        AnalysePeaksTab               matlab.ui.container.Tab
        onrateplot                    matlab.ui.control.UIAxes
        PlotratesButton               matlab.ui.control.Button
        SaverateplotButton            matlab.ui.control.Button
        LoadpeakdataButton            matlab.ui.control.Button
        sLabel                        matlab.ui.control.Label
        RateEditFieldLabel            matlab.ui.control.Label
        OnRateDispField               matlab.ui.control.NumericEditField
        ConcEditFieldLabel            matlab.ui.control.Label
        ConcEditField                 matlab.ui.control.NumericEditField
        uMLabel                       matlab.ui.control.Label
        AddConctoplotButton           matlab.ui.control.Button
        FittorateButton               matlab.ui.control.Button
        offrateplot                   matlab.ui.control.UIAxes
        FilepathTextArea_2Label       matlab.ui.control.Label
        peakdataFilePath              matlab.ui.control.TextArea
        OnrateLabel                   matlab.ui.control.Label
        onrateDisp                    matlab.ui.control.NumericEditField
        MsLabel                       matlab.ui.control.Label
        sLabel_3                      matlab.ui.control.Label
        OffRateDispEditFieldLabel     matlab.ui.control.Label
        OffRateDispField              matlab.ui.control.NumericEditField
        RatefrommeanLabel             matlab.ui.control.Label
        OnRateFromMean                matlab.ui.control.NumericEditField
        RatefrommeanLabel_2           matlab.ui.control.Label
        OffRateFromMean               matlab.ui.control.NumericEditField
        concdependence_2              matlab.ui.control.UIAxes
        Tree                          matlab.ui.container.Tree
        PeaksNode                     matlab.ui.container.TreeNode
        UITable                       matlab.ui.control.Table
        msLabel_2                     matlab.ui.control.Label
        GrouppeakswithinLabel_2       matlab.ui.control.Label
        DurationThresholdSpinner_2    matlab.ui.control.Spinner
        ReGrouppeaksButton_2          matlab.ui.control.Button
        AnalysisPlotsTab              matlab.ui.container.Tab
        histheights                   matlab.ui.control.UIAxes
        PlotHeightsButton             matlab.ui.control.Button
        fmLabel                       matlab.ui.control.Label
        MeanheightEditFieldLabel      matlab.ui.control.Label
        MeanheightDispField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        % File name and path name
        fileProps = struct('filename', [], 'pathname',[],'FileInfo',struct([]),'ext','.txt','oldFolder',[]);
        
        % Data from text file or tdms files
        iData = struct('time',[],'plot1',[],'dt_plot1',[],'plot2',[],'dt_plot2',[],...
            'modeNumber',1,'tTime',100,'comment','','sTime',0,'eTime',20,'sIndex',1,...
            'eIndex',1000,'duration',20,'samplingTime',0.0192, 'numModes',1);
        
        % Properties of analysis (rate measurements and peka height histograms)
        aData = struct('duration',[],'locs',[],'peakHeights',[],'conc',1,'concVsRate',[],...
            'onRate',0,'offRate',0);
        
        % Detrend properties for analysing swData
        fData = struct('filterType','Golay','winSize',101);
        
        % Properties of the peak finder
        peakFinder = struct('N',10,'sdevplot1',0,'sdevplot2',0,'ampThresholdplot1',3,...
            'ampThresholdplot2',3,'slopeThresholdplot1',1e-4,'slopeThresholdplot2',1e-4,...
            'smoothWidthplot1',101','smoothWidthplot2',101,'smoothTypeplot1',3,...
            'smoothTypeplot2',3,'thresholdDur',0.05,'groupWidthplot1',200,...
            'groupWidthplot2',200,'nflag','Off');
        
        % Properties of the step finder
        stepFinder = struct('startPoint',100,'leftInterval',10,'leftOffset',0,...
            'rightInterval',10,'rightOffset',1,...
            'steps', struct('time',[],'height',[]),...
            'T_plot1',[],'H_plot1',[],'T_plot2',[],'H_plot2',[]);
        
        % structures to the stores resulting peak information
        rData = struct('plot1Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot2Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot1PeaksF',struct([]),'plot2PeaksF',struct([]),...
            'sPeaks1F',struct([]),'sPeaks2F',struct([]),...
            'plot1StepsF',struct('t',[],'h',[]),'plot2StepsF',struct('t',[],'h',[]) );
        
        % plot properties
        plotProps = struct('dToggle','Off','fToggle','Off','c_axes',[],'titles',...
            {{'plot1','plot2'}},'winsize',101,'xlims',[0 20]);
        
        % temp variables
        tmp = struct('plot1Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),...
            'plot2Peaks',struct('h',[],'l',[],'w',[],'p',[],'area',[]),'plot1PeaksF',struct([]),...
            'plot2PeaksF',struct([]));
        
        % Error handling structures
        errmsg = '';% error message
    end
    
    methods (Access = private)
        
        % Function to assign the properties on one structure into an internal variable property
        function assignToStruct(app,oStruct,iStruct)
            names = fieldnames(iStruct);
            for i = 1:length(names)
                app.(oStruct).(names{i}) = iStruct.(names{i});
            end
        end
        
        % Disable controls at startup or while executing certain parts of the code
        function disablecontrols(app)
            app.StartTimeSpinner.Enable = 'Off';
            app.PlotdurationSpinner.Enable = 'Off';
            app.nStdSpinner.Enable = 'Off';
            app.ModenumberDropDown.Enable = 'Off';
            app.PeakAnalysisPanel.Visible = 'Off';
            app.StepDetectionPanel.Visible = 'Off';
            app.SaveDataButton.Enable = 'Off';
        end
        
        % Enable controls after succesful loading of data or analysis
        function enablecontrols(app)
            app.StartTimeSpinner.Enable = 'On';
            app.PlotdurationSpinner.Enable = 'On';
            app.nStdSpinner.Enable = 'On';
            app.ModenumberDropDown.Enable = 'On';
            app.PeakAnalysisPanel.Visible = 'On';
            app.StepDetectionPanel.Visible = 'On';
            app.SaveDataButton.Enable = 'On';
        end
        
        % resets all the data structures to initial values
        function resetvariables(app)
            names = fieldnames(app.rData);
            for i = 1:length(names)
                if contains(names{i},'F')
                    app.rData.(names{i}) = struct([]);
                else
                    app.rData.(names{i}) = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                end
            end
            h = findobj(app.DataAnalysisUIFigure,'Type','axes');
            for i = 1:length(h)
                cla(h(i));
            end
        end
        
        % Gets data from the text or TDMS files and plots them
        function fetchdata(app)
            try
            results = getData(app.fileProps,app.iData.sTime,app.iData.eTime,app.iData.Fs);
            assignToStruct(app,'iData',results);
            [results, err] = filterdata(app.iData,{'plot1','plot2'},app.fData.filterType,app.fData.winSize);
            if ~isempty(err)
                printerror(app,err);
                return
            end
            assignToStruct(app,'iData',results);
            [results, err] = detrendData(app.iData,{'plot1','plot2'});
            if ~isempty(err)
                printerror(app, err);
                return
            end
            assignToStruct(app,'iData',results);
            results = detrendData(app.iData,{'fplot1','fplot2'});
            assignToStruct(app,'iData',results);
            if app.iData.tTime-app.iData.duration > 0
                app.StartTimeSpinner.Limits = [0 app.iData.tTime-app.iData.duration];
            end
            app.PlotdurationSpinner.Limits = [1/app.iData.Fs app.iData.tTime-app.iData.sIndex];
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            results = getsdev(app.iData, 10);
            assignToStruct(app,'peakFinder',results);
            app.Plot1stdDisp.Value = app.peakFinder.sdevplot1;
            app.Plot2stdDisp.Value = app.peakFinder.sdevplot2;
            for i = 1:length(app.iData.plot1(1,:))
                app.ModenumberDropDown.Items(i) = {num2str(i)};
            end
            app.plotProps.titles = app.iData.plotTitles;
            app.plot1.Title.String = app.plotProps.titles{1};
            app.plot2.Title.String = app.plotProps.titles{2};
            catch err
                printerror(app, err);
            end
        end
        
        
        function peakfinder(app)
            
            % Sets the data for finding peaks and calls the findpeaksindata function
            
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2] );
            nm = app.iData.modeNumber;
            xdata = app.iData.time;
            if strcmpi(app.plotProps.dToggle,'On')
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'dt_fplot1','dt_fplot2'};
                else
                    fields = {'dt_plot1','dt_plot2'};
                end
            else
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'fplot1','fplot2'};
                else
                    fields = {'plot1','plot2'};
                end
            end
            for i = 1:length(fields)
                ydata = app.iData.(fields{i})(:,nm);
                if strcmpi(app.PeakDetectionMethodDropDown.Value,'FindPeaks Matlab')
                    [oStruct.(['plot',num2str(i),'Peaks']),err] = findpeaksindata(ydata, xdata,...
                        app.peakFinder.(['sdevplot',num2str(i)]),app.nStdSpinner.Value,...
                        app.peakFinder.(['ampThresholdplot',num2str(i)]),app.peakFinder.nflag);
                else
                    [oStruct.(['plot',num2str(i),'Peaks']),err] = findpeaksindata2(ydata, xdata,...
                        app.peakFinder.(['slopeThresholdplot',num2str(i)]),app.peakFinder.(['ampThresholdplot',num2str(i)]),...
                        app.peakFinder.(['smoothWidthplot',num2str(i)]), app.peakFinder.(['groupWidthplot',num2str(i)]),...
                        app.peakFinder.(['smoothTypeplot',num2str(i)]),app.peakFinder.nflag);
                end
                if ~isempty(err)
                    printerror(app, err);
                    return
                end
                peakfields = fieldnames(oStruct.(['plot',num2str(i),'Peaks']));
                if ~isempty(oStruct.(['plot',num2str(i),'Peaks']).l)
                    [~,I] = sort(oStruct.(['plot',num2str(i),'Peaks']).l);
                    for j = 1:length(peakfields)
                        oStruct.(['plot',num2str(i),'Peaks']).(peakfields{j}) = oStruct.(['plot',num2str(i),'Peaks']).(peakfields{j})(I);
                    end
                    [oStruct.(['plot',num2str(i),'PeaksF']),err] = grouppeaks(oStruct.(['plot',num2str(i),'Peaks']),...
                        app.peakFinder.thresholdDur);
                    if ~isempty(err)
                        printerror(app, err);
                        return
                    end
                    assignToStruct(app,'tmp',oStruct);
                    if ~isempty(app.tmp.(['plot',num2str(i),'Peaks']).l)
                        err = plotpeaks(app.(['plot',num2str(i)]),app.iData, app.tmp.(['plot',num2str(i),'PeaksF']),fields{i});
                        if ~isempty(err)
                            printerror(app, err);
                            return
                        end
                        err = plotsubpeaks(app.(['plot',num2str(i)]),app.iData, app.tmp.(['plot',num2str(i),'Peaks']),fields{i});
                        if ~isempty(err)
                            printerror(app, err);
                            return
                        end
                    end
                end
            end
        end
        
        function printerror(app, err)
            %% Prints the error message as contained in 'err' in a UI errormsg dialog box
            
            title = err.identifier(8:end);
            uialert(app.DataAnalysisUIFigure,err.message,title);
            enablecontrols(app);
        end
        
        
        function findsteps(app)
            %% Function to find the steps in the data displayed in each window
            nm = app.iData.modeNumber;
            if strcmpi(app.plotProps.dToggle,'On')
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'dt_fplot1','dt_fplot2'};
                else
                    fields = {'dt_plot1','dt_plot2'};
                end
            else
                if strcmpi(app.plotProps.fToggle,'On')
                    fields = {'fplot1','fplot2'};
                else
                    fields = {'plot1','plot2'};
                end
            end
            for i =1:length(fields)
                y = app.iData.(fields{i})(:,nm)';
                time = ['T_',fields{i}];
                height = ['H_',fields{i}];
                [app.stepFinder.(time), app.stepFinder.(height), err] = stepDetect(app.(['plot',num2str(i)]), y,app.iData.time, app.stepFinder);
                if ~isempty(err)
                    printerror(app, err);
                    return
                end
                app.(['plot',num2str(i),'heightfmEditField']).Value = app.stepFinder.(height);
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.iData.modeNumber = str2double(app.ModenumberDropDown.Value);
            app.peakFinder.thresholdDur = app.DurationThresholdSpinner.Value/1000;
            app.fData.winSize = app.WindowsizeSpinner.Value;
            app.plotProps.nflag = app.NegativepeaksSwitch.Value;
            app.peakFinder.threholdplot1 = app.Plot1Ampthreshold.Value;
            app.peakFinder.threholdplot2 = app.Plot2Ampthreshold.Value;
            app.StartTimeSpinner.Step = app.PlotdurationSpinner.Value;
            app.XStartSpinner.Limits = [app.iData.sTime app.iData.eTime];
            app.XEndSpinner.Limits = [app.iData.sTime app.iData.eTime];
            app.XStartSpinner.Value = app.iData.sTime;
            app.XEndSpinner.Value = app.iData.eTime;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
            app.iData.modeNumber = 1;
            app.StartTimeSpinner.Value = 1;
            app.iData.sTime = 1;
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.ModenumberDropDown.Items = {'1'};
            resetvariables(app);
            disablecontrols(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            startupFcn(app);
            resetvariables(app);
            
            [app.fileProps.filename, app.fileProps.pathname] =...
                uigetfile({'*.txt';'*.tdms'},'File Open Dialog');
            if app.fileProps.filename
                app.fileProps.oldFolder = cd(app.fileProps.pathname);
                fileprops = setfileProperties(fullfile(app.fileProps.pathname,...
                    app.fileProps.filename));
                assignToStruct(app,'fileProps',fileprops);
                results = getHeaders(app.fileProps);
                assignToStruct(app,'iData',results);
                fetchdata(app);
                app.FilepathTextArea.Value = fullfile(app.fileProps.pathname,...
                    app.fileProps.filename);
                app.CommentTextArea.Value = app.iData.comment;
                enablecontrols(app);
                %                 app.plotProps.titles = inputdlg({'Plot1','Plot2'},'Plot titles',1,{'lambda','fwhm'});
                %app.PeaksPlot.Title.String = [app.plotProps.titles{1},' Peak Heights'];
                %app.plot2peaks.Title.String = [app.plotProps.titles{2},' Peak Heights'];
                %app.WidthPlot.Title.String = [app.plotProps.titles{1},' Peak Width '];
                %app.stepheight.Title.String = [app.plotProps.titles{2},' Peak Width'];
                if strcmpi(app.fileProps.ext,'.tdms')
                    app.ChannelDropDown.Enable = true;
                    app.ChannelDropDown_2.Enable = true;
                end
                app.Plot1GroupWidth.Enable = false;
                app.Plot1SmoothWidth.Enable = false;    
                app.Plot1Smoothtype.Enable = false;
                app.Plot1Slopethreshold.Enable = false;
                app.Plot2GroupWidth.Enable = false;
                app.Plot2SmoothWidth.Enable = false;
                app.Plot2Smoothtype.Enable = false;
                app.Plot2Slopethreshold.Enable = false;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.DataAnalysisUIFigure);
        end

        % Button pushed function: clearvariablesButton
        function clearvariablesButtonPushed(app, event)
            resetvariables(app);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            folder = uigetdir(app.fileProps.pathname);
%             paths = strsplit(app.fileProps.pathname,'\');
%             measNumber = paths{5};
            filename = strsplit(app.fileProps.filename,'.');
            filename = filename{1};
            paths = fullfile(folder,filename);
            savetomat(paths, app.rData,app.plotProps.titles,...
                app.iData.Fs,app.peakFinder.thresholdDur,app.iData.comment);
        end

        % Value changed function: PlotdetrendeddataSwitch
        function PlotdetrendeddataSwitchValueChanged(app, event)
            app.plotProps.dToggle = app.PlotdetrendeddataSwitch.Value;
            plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Button pushed function: SaveplotsButton
        function SaveplotsButtonPushed(app, event)
            path = fullfile(app.fileProps.pathname,app.fileProps.filename);
            xlabel = 'Time [s]';
            ylabel_1 = '$\Delta \lambda$ [fm]';
            ylabel_2 = '$\Delta \kappa$ [fm]';
            err = saveplots(app.iData,path, app.tmp, app.plotProps.dToggle,...
                app.plotProps.fToggle, xlabel,ylabel_1,ylabel_2, app.plot1.XLim,app.plot1.YLim);
            if ~isempty(err)
                printerror(app, err);
                return
            end
        end

        % Value changed function: ModenumberDropDown
        function ModenumberDropDownValueChanged(app, event)
            app.iData.modeNumber = str2double(app.ModenumberDropDown.Value);
            plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Value changed function: StartTimeSpinner
        function StartTimeSpinnerValueChanged(app, event)
            app.iData.sTime = app.StartTimeSpinner.Value;
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
            app.XStartSpinner.Limits = app.plotProps.xlims;
            app.XEndSpinner.Limits = app.plotProps.xlims;
            app.XEndSpinner.Value = app.plotProps.xlims(2);
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);
        end

        % Value changed function: PlotdurationSpinner
        function PlotdurationSpinnerValueChanged(app, event)
            app.iData.eTime = app.iData.sTime + app.PlotdurationSpinner.Value;
            app.StartTimeSpinner.Step = app.PlotdurationSpinner.Value;
            app.plotProps.xlims = [app.iData.sTime app.iData.eTime];
            app.XStartSpinner.Limits = app.plotProps.xlims;
            app.XEndSpinner.Limits = app.plotProps.xlims;
            app.XEndSpinner.Value = app.plotProps.xlims(2);
            disablecontrols(app);
            fetchdata(app);
            enablecontrols(app);
        end

        % Value changed function: nStdSpinner
        function nStdSpinnerValueChanged(app, event)
            app.peakFinder.N = app.nStdSpinner.Value;
            [results, err] = getsdev(app.iData,app.peakFinder.N);
            if ~isempty(err)
                printerror(app, err);
                return
            end
            assignToStruct(app,'peakFinder',results);
            app.Plot1stdDisp.Value = app.peakFinder.sdevplot1;
            app.Plot2stdDisp.Value = app.peakFinder.sdevplot2;
            
        end

        % Value changed function: FiltertypeDropDown
        function FiltertypeDropDownValueChanged(app, event)
            app.fData.filterType = app.FiltertypeDropDown.Value(~isspace(app.FiltertypeDropDown.Value));
            
        end

        % Value changed function: WindowsizeSpinner
        function WindowsizeSpinnerValueChanged(app, event)
            app.fData.winSize = app.WindowsizeSpinner.Value;
            
        end

        % Button pushed function: FilterdataButton
        function FilterdataButtonPushed(app, event)
            disablecontrols(app);
            [results,err] = filterdata(app.iData,{'plot1','plot2'},...
                app.fData.filterType,app.fData.winSize);
            if ~isempty(err)
                printerror(app,err);
                return
            end
            assignToStruct(app,'iData',results);
            [results,err] = detrendData(app.iData,{'fplot1','fplot2'});
            if ~isempty(err)
                printerror(app,err);
                return
            end
            assignToStruct(app,'iData',results);
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            if ~isempty(err)
                printerror(app,err);
                return
            end
            enablecontrols(app);
        end

        % Value changed function: Plot1Ampthreshold
        function Plot1AmpthresholdValueChanged(app, event)
            app.peakFinder.ampThresholdplot1 = app.Plot1Ampthreshold.Value;
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            if ~isempty(err)
                printerror(app,err);
                return
            end
        end

        % Value changed function: Plot2Ampthreshold
        function Plot2AmpthresholdValueChanged(app, event)
            app.peakFinder.ampThresholdplot2 = app.Plot2Ampthreshold.Value;
            err = plotdata([app.plot1,app.plot2],app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
            if ~isempty(err)
                printerror(app,err);
                return
            end
        end

        % Value changed function: NegativepeaksSwitch
        function NegativepeaksSwitchValueChanged(app, event)
            app.peakFinder.nflag = app.NegativepeaksSwitch.Value;
            
        end

        % Button pushed function: findpeaksButton
        function findpeaksButtonPushed(app, event)
            disablecontrols(app);
            peakfinder(app);
            enablecontrols(app);
        end

        % Button pushed function: AddPlot1PeakButton
        function AddPlot1PeakButtonPushed(app, event)
            if isempty(app.rData.plot1Peaks)
                app.rData.plot1Peaks = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                app.rData.plot1PeaksF = struct([]);
            end
            names = fieldnames(app.tmp.plot1Peaks);
            for i = 1:length(names)
                app.rData.plot1Peaks.(names{i}) = [app.rData.plot1Peaks.(names{i});app.tmp.plot1Peaks.(names{i})];
            end
            app.rData.plot1PeaksF = [app.rData.plot1PeaksF,app.tmp.plot1PeaksF];
            app.rData.plot1PeaksF = uniquestruct(app.rData.plot1PeaksF);
            plotpeakinfo(app.PeaksPlot, app.rData, {'plot1PeaksF'},'h',[0,0.447,0.741]);
            plotpeakinfo(app.WidthPlot, app.rData,{'plot1PeaksF'},'w',[0,0.447,0.741]);
        end

        % Button pushed function: AddPlot2PeakButton
        function AddPlot2PeakButtonPushed(app, event)
            if isempty(app.rData.plot2Peaks)
                app.rData.plot2Peaks = struct('h',[],'l',[],'w',[],'p',[],'area',[]);
                app.rData.plot2PeaksF = struct([]);
            end
            names = fieldnames(app.tmp.plot2Peaks);
            for i = 1:length(names)
                app.rData.plot2Peaks.(names{i}) = [app.rData.plot2Peaks.(names{i});app.tmp.plot2Peaks.(names{i})];
            end
            app.rData.plot2PeaksF = [app.rData.plot2PeaksF,app.tmp.plot2PeaksF];
            app.rData.plot2PeaksF = uniquestruct(app.rData.plot2PeaksF);
            plotpeakinfo(app.PeaksPlot, app.rData, {'plot2PeaksF'},'h',[0.85 0.325 0.098]);
            plotpeakinfo(app.WidthPlot, app.rData,{'plot2PeaksF'},'w',[0.85 0.325 0.098]);
            
        end

        % Value changed function: WinsizeSpinner
        function WinsizeSpinnerValueChanged(app, event)
            app.plotProps.winsize = app.WinsizeSpinner.Value;
        end

        % Value changed function: XStartSpinner
        function XStartSpinnerValueChanged(app, event)
            app.plotProps.xlims(1) = app.XStartSpinner.Value;
            app.plot1.XLim = app.plotProps.xlims;
            app.plot2.XLim = app.plotProps.xlims;
        end

        % Value changed function: XEndSpinner
        function XEndSpinnerValueChanged(app, event)
            app.plotProps.xlims(2) = app.XEndSpinner.Value;
            app.XStartSpinner.Limits(2) = app.XEndSpinner.Value-0.1;
            app.plot1.XLim = app.plotProps.xlims;
            app.plot2.XLim = app.plotProps.xlims;
        end

        % Value changed function: Plotfiltered
        function PlotfilteredValueChanged(app, event)
            app.plotProps.fToggle = app.Plotfiltered.Value;
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Value changed function: DurationThresholdSpinner, 
        % DurationThresholdSpinner_2
        function DurationThresholdSpinnerValueChanged(app, event)
            app.peakFinder.thresholdDur = app.DurationThresholdSpinner.Value/1000;
            
        end

        % Button pushed function: ReGrouppeaksButton
        function ReGrouppeaksButtonPushed(app, event)
            fields = {'plot1Peaks','plot2Peaks'};
            for i = length(fields)
                [app.rData.([fields{i},'F']),err] = grouppeaks(app.rData.(fields{i}),...
                    app.peakFinder.threholdDur,app.DataAnalysisUIFigure);
            end
            if ~isempty(err)
                printerror(app,err);
                return
            end
        end

        % Value changed function: Plot1SmoothWidth
        function Plot1SmoothWidthValueChanged(app, event)
            app.peakFinder.smoothWidthplot1 = app.Plot1SmoothWidth.Value;
            
        end

        % Value changed function: Plot1GroupWidth
        function Plot1GroupWidthValueChanged(app, event)
            app.peakFinder.groupWidthplot1  = app.Plot1GroupWidth.Value;
            
        end

        % Value changed function: Plot1Slopethreshold
        function Plot1SlopethresholdValueChanged(app, event)
            app.peakFinder.slopeThresholdplot1 = app.Plot1Slopethreshold.Value;
            
        end

        % Value changed function: Plot1Smoothtype
        function Plot1SmoothtypeValueChanged(app, event)
            app.peakFinder.smoothTypeplot1 = app.Plot1Smoothtype.Value;
            
        end

        % Value changed function: Plot2SmoothWidth
        function Plot2SmoothWidthValueChanged(app, event)
            app.peakFinder.smoothWidthplot2  = app.Plot2SmoothWidth.Value;
            
        end

        % Value changed function: Plot2GroupWidth
        function Plot2GroupWidthValueChanged(app, event)
            app.peakFinder.groupWidthplot2  = app.Plot2GroupWidth.Value;
            
        end

        % Value changed function: Plot2Slopethreshold
        function Plot2SlopethresholdValueChanged(app, event)
            app.peakFinder.slopeThresholdplot2 = app.Plot2Slopethreshold.Value;
            
        end

        % Value changed function: Plot2Smoothtype
        function Plot2SmoothtypeValueChanged(app, event)
            app.peakFinder.smoothTypeplot2 = app.Plot2Smoothtype.Value;
            
        end

        % Button pushed function: panPlots
        function panPlotsButtonPushed(app, event)
            pan(app.plot1,'on');
            pan(app.plot2,'on');
        end

        % Button pushed function: resetPlots
        function resetPlotsButtonPushed(app, event)
            zoom(app.plot1,'off');
            app.plot1.XLim = app.plotProps.xlims;
            app.plot1.YLimMode = 'auto';
            zoom(app.plot2,'off');
            app.plot2.XLim = app.plotProps.xlims;
            app.plot2.YLimMode = 'auto';
        end

        % Button pushed function: zoomPlots
        function zoomPlotsButtonPushed(app, event)
            zoom(app.plot1,'on');
            zoom(app.plot2,'on');
        end

        % Button pushed function: getPointsPlots
        function getPointsPlotsButtonPushed(app, event)
            [xi,yi] = ginput(1);
        end

        % Button pushed function: LoadpeakdataButton
        function LoadpeakdataButtonPushed(app, event)
            [app.aData.filename, app.aData.pathname] =...
                uigetfile('*.mat','Load peak data from mat file');
            app.peakdataFilePath.Value = fullfile(app.aData.pathname,app.aData.filename);
            app.PeaksNode.Children.delete;
            if app.aData.filename
                app.aData.Peaks = load(fullfile(app.aData.pathname,app.aData.filename));
                fieldnames = fields(app.aData.Peaks);
                for j = 1:length(fieldnames)
                    uitreenode(app.PeaksNode,'Text',fieldnames{j});
                end
                if ~isempty(app.aData.Peaks)
                    app.ReGrouppeaksButton_2.Enable = true;
                end
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;
            
            % Get field name from node text.
            fieldName = selectedNode.Text;
            
            % Extract this field data from app.Data.
            if isempty(selectedNode.Children)
                data = struct2table(app.aData.Peaks.(fieldName),'AsArray',true);
                % Update the fields with the appropriate data when the patient name is selected.
                if ~isempty(data)
                    app.UITable.ColumnName = data.Properties.VariableNames;
                    app.UITable.Data = data;
                end
                if iscell(data.l)
                    interV = diff(cell2mat(data.l));
                else
                    interV = diff(data.l);
                end
                [~,~,app.aData.onRate] = probDist(interV,1/app.aData.Peaks.daqProps.Fs,app.onrateplot);
                app.OnRateDispField.Value  = app.aData.onRate.b;
                if ismember('duration',data.Properties.VariableNames)
                    interV = data.duration;
                    [~,~,app.aData.offRate] = probDist(interV,1/app.aData.Peaks.daqProps.Fs,app.offrateplot);
                    app.OffRateDispField.Value  = app.aData.offRate.b;
                end
            end
        end

        % Button pushed function: RedetrendButton
        function RedetrendButtonPushed(app, event)
            if strcmpi(app.plotProps.fToggle,'on')
                results = detrendData(app.iData,{'fplot1','fplot2'},app.plotProps.winsize);
            else
                results = detrendData(app.iData,{'plot1','plot2'},app.plotProps.winsize);
            end
            assignToStruct(app, 'iData',results);
            plotdata([app.plot1,app.plot2], app.iData, app.plotProps.dToggle,...
                app.plotProps.fToggle, app.plotProps.xlims,[app.peakFinder.ampThresholdplot1,app.peakFinder.ampThresholdplot2]);
        end

        % Button pushed function: AddConctoplotButton
        function AddConctoplotButtonPushed(app, event)
            
        end

        % Button pushed function: FittorateButton
        function FittorateButtonPushed(app, event)
            
        end

        % Button pushed function: AddspecialPeaksButton
        function AddspecialPeaksButtonPushed(app, event)
            app.rData.sPeaks1F = [app.rData.sPeaks1F,app.tmp.plot1PeaksF];
            app.rData.sPeaks1F = uniquestruct(app.rData.sPeaks1F);
            app.rData.sPeaks2F = [app.rData.sPeaks2F,app.tmp.plot2PeaksF];
            app.rData.sPeaks2F = uniquestruct(app.rData.sPeaks2F);
            
        end

        % Button pushed function: ReGrouppeaksButton_2
        function ReGrouppeaksButton_2Pushed(app, event)
            fields = fieldnames(app.aData.Peaks);
            names1 = fields(contains(fields,'_1'));
            names2 = fields(contains(fields,'_2'));
            for i = length(names1)
                [app.aData.Peaks.(names2{i}),err] = grouppeaks(app.aData.Peaks.(names1{i}),...
                    app.DurationThresholdSpinner_2.Value);
            end
            if ~isempty(err)
                printerror(app,err);
                return
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end

        % Button pushed function: SaverateplotButton
        function SaverateplotButtonPushed(app, event)
            folder = uigetdir();          
            filename = [app.aData.filename,'_onrate'];
            path = fullfile(folder,filename);
            err  = saveplots2(app.onrateplot, path);
            if ~isempty(err)
                printerror(app, err);
                return
            end
            filename = [app.aData.filename,'_offrate'];
            path = fullfile(app.fileProps.pathname,filename);
            err  = saveplots2(app.offrateplot, path);
            if ~isempty(err)
                printerror(app, err);
                return
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            
        end

        % Button pushed function: PlotratesButton
        function PlotratesButtonPushed(app, event)
            TreeSelectionChanged(app, event);
        end

        % Button pushed function: AddStepPlot1Button
        function AddStepPlot1ButtonPushed(app, event)
            if isempty(app.rData.plot1StepsF)
                app.rData.plot1StepsF = struct('t',[],'h',[]);
            end
            if isempty(app.rData.plot2StepsF)
                app.rData.plot2StepsF = struct('t',[],'h',[]);
            end
            app.rData.plot1StepsF.t = [app.rData.plot1StepsF.t; app.stepFinder.T_plot1];
            app.rData.plot1StepsF.h  = [app.rData.plot1StepsF.h ; app.stepFinder.H_plot1];
            plot(app.stepheight, app.rData.plot1StepsF.t, app.rData.plot1StepsF.h,'v','Color',[0,0.447,0.741]);
            hold(app.stepheight,'on');
            plot(app.stepheight, app.rData.plot2StepsF.t, app.rData.plot2StepsF.h,'v','Color',[0.85 0.325 0.098]);
            hold(app.stepheight,'off');
        end

        % Value changed function: startPointSpinner
        function startPointSpinnerValueChanged(app, event)
            app.stepFinder.startPoint = app.startPointSpinner.Value;
            findsteps(app);
        end

        % Value changed function: rightIntervalSpinner
        function rightIntervalSpinnerValueChanged(app, event)
            app.stepFinder.rightInterval = app.rightIntervalSpinner.Value;
            findsteps(app);
        end

        % Value changed function: leftOffsetSpinner
        function leftOffsetSpinnerValueChanged(app, event)
            app.stepFinder.leftOffset = app.leftOffsetSpinner.Value;
            findsteps(app);
        end

        % Value changed function: leftIntervalSpinner
        function leftIntervalSpinnerValueChanged(app, event)
            app.stepFinder.leftInterval = app.leftIntervalSpinner.Value;
            findsteps(app);
        end

        % Value changed function: rightOffsetSpinner
        function rightOffsetSpinnerValueChanged(app, event)
            app.stepFinder.rightOffset = app.rightOffsetSpinner.Value;
            findsteps(app);
        end

        % Button pushed function: AddStepPlot2Button
        function AddStepPlot2ButtonPushed(app, event)
            if isempty(app.rData.plot1StepsF)
                app.rData.plot1StepsF = struct('t',[],'h',[]);
            end
            if isempty(app.rData.plot2StepsF)
                app.rData.plot2StepsF = struct('t',[],'h',[]);
            end
            app.rData.plot2StepsF.t = [app.rData.plot2StepsF.t; app.stepFinder.T_plot2];
            app.rData.plot2StepsF.h  = [app.rData.plot2StepsF.h ; app.stepFinder.H_plot2];
            plot(app.stepheight, app.rData.plot1StepsF.t, app.rData.plot1StepsF.h,'v','Color',[0,0.447,0.741]);
            hold(app.stepheight,'on');
            plot(app.stepheight, app.rData.plot2StepsF.t, app.rData.plot2StepsF.h,'v','Color',[0.85 0.325 0.098]);
            hold(app.stepheight,'off');
        end

        % Value changed function: PeakDetectionMethodDropDown
        function PeakDetectionMethodDropDownValueChanged(app, event)
            value = app.PeakDetectionMethodDropDown.Value;
            if strcmpi(value,'FindPeaks Matlab')
                app.Plot1GroupWidth.Enable = false;
                app.Plot1SmoothWidth.Enable = false;    
                app.Plot1Smoothtype.Enable = false;
                app.Plot1Slopethreshold.Enable = false;
                app.Plot2GroupWidth.Enable = false;
                app.Plot2SmoothWidth.Enable = false;
                app.Plot2Smoothtype.Enable = false;
                app.Plot2Slopethreshold.Enable = false;
            else
                app.Plot1GroupWidth.Enable = true;
                app.Plot1SmoothWidth.Enable = true;    
                app.Plot1Smoothtype.Enable = true;
                app.Plot1Slopethreshold.Enable = true;
                app.Plot2GroupWidth.Enable = true;
                app.Plot2SmoothWidth.Enable = true;
                app.Plot2Smoothtype.Enable = true;
                app.Plot2Slopethreshold.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAnalysisUIFigure = uifigure('Visible', 'off');
            app.DataAnalysisUIFigure.Name = 'Data Analysis';
            app.TabGroup = uitabgroup(app.DataAnalysisUIFigure);
            app.FindPeaksTab = uitab(app.TabGroup);
            app.FindPeaksTab.Title = 'Find Peaks';
            app.OpenButton = uibutton(app.FindPeaksTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ExitButton = uibutton(app.FindPeaksTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveDataButton = uibutton(app.FindPeaksTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.clearvariablesButton = uibutton(app.FindPeaksTab, 'push');
            app.clearvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @clearvariablesButtonPushed, true);
            app.clearvariablesButton.Text = 'clear variables';
            app.CommentTextAreaLabel = uilabel(app.FindPeaksTab);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.FindPeaksTab);
            app.CommentTextArea.Editable = 'off';
            app.FilepathTextAreaLabel = uilabel(app.FindPeaksTab);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea = uitextarea(app.FindPeaksTab);
            app.FilepathTextArea.Editable = 'off';
            app.PeakAnalysisPanel = uipanel(app.FindPeaksTab);
            app.PeakAnalysisPanel.Title = 'Peak Analysis';
            app.FilterpropertiesPanel = uipanel(app.PeakAnalysisPanel);
            app.FilterpropertiesPanel.Title = 'Filter properties';
            app.FiltertypeDropDownLabel = uilabel(app.FilterpropertiesPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.FiltertypeDropDown = uidropdown(app.FilterpropertiesPanel);
            app.FiltertypeDropDown.Items = {'averaging', 'moving average', 'median', 'golay', 'gaussian'};
            app.FiltertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltertypeDropDownValueChanged, true);
            app.FiltertypeDropDown.Value = 'median';
            app.WindowsizeSpinnerLabel = uilabel(app.FilterpropertiesPanel);
            app.WindowsizeSpinnerLabel.Text = {'Window'; 'size'};
            app.WindowsizeSpinner = uispinner(app.FilterpropertiesPanel);
            app.WindowsizeSpinner.Step = 2;
            app.WindowsizeSpinner.Limits = [5 Inf];
            app.WindowsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSpinnerValueChanged, true);
            app.WindowsizeSpinner.Value = 101;
            app.FilterdataButton = uibutton(app.FilterpropertiesPanel, 'push');
            app.FilterdataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterdataButtonPushed, true);
            app.FilterdataButton.Text = 'Filter data';
            app.Plotfiltered = uiswitch(app.FilterpropertiesPanel, 'slider');
            app.Plotfiltered.ValueChangedFcn = createCallbackFcn(app, @PlotfilteredValueChanged, true);
            app.PlotfilteredLabel = uilabel(app.FilterpropertiesPanel);
            app.PlotfilteredLabel.Text = 'Plot filtered?';
            app.Plot1PeaksPanel = uipanel(app.PeakAnalysisPanel);
            app.Plot1PeaksPanel.Title = 'Plot1 Peaks';
            app.sdevLabel = uilabel(app.Plot1PeaksPanel);
            app.sdevLabel.Text = 'sdev';
            app.Plot1stdDisp = uieditfield(app.Plot1PeaksPanel, 'numeric');
            app.Plot1stdDisp.Editable = 'off';
            app.SlopeThresholdLabel_2 = uilabel(app.Plot1PeaksPanel);
            app.SlopeThresholdLabel_2.Text = {'Slope'; ' Threshold'};
            app.Plot1Slopethreshold = uieditfield(app.Plot1PeaksPanel, 'numeric');
            app.Plot1Slopethreshold.Limits = [0 Inf];
            app.Plot1Slopethreshold.ValueChangedFcn = createCallbackFcn(app, @Plot1SlopethresholdValueChanged, true);
            app.Plot1Slopethreshold.Value = 0.0001;
            app.GroupwidthLabel = uilabel(app.Plot1PeaksPanel);
            app.GroupwidthLabel.Text = {'Group'; 'width'};
            app.Plot1GroupWidth = uieditfield(app.Plot1PeaksPanel, 'numeric');
            app.Plot1GroupWidth.Limits = [0 Inf];
            app.Plot1GroupWidth.ValueChangedFcn = createCallbackFcn(app, @Plot1GroupWidthValueChanged, true);
            app.Plot1GroupWidth.Value = 101;
            app.SmoothwidthLabel_2 = uilabel(app.Plot1PeaksPanel);
            app.SmoothwidthLabel_2.Text = {'Smooth'; 'width'};
            app.Plot1SmoothWidth = uieditfield(app.Plot1PeaksPanel, 'numeric');
            app.Plot1SmoothWidth.Limits = [0 Inf];
            app.Plot1SmoothWidth.ValueChangedFcn = createCallbackFcn(app, @Plot1SmoothWidthValueChanged, true);
            app.Plot1SmoothWidth.Value = 101;
            app.SmoothtypeSpinner_2Label = uilabel(app.Plot1PeaksPanel);
            app.SmoothtypeSpinner_2Label.Text = 'Smooth type';
            app.Plot1Smoothtype = uispinner(app.Plot1PeaksPanel);
            app.Plot1Smoothtype.Limits = [1 3];
            app.Plot1Smoothtype.ValueChangedFcn = createCallbackFcn(app, @Plot1SmoothtypeValueChanged, true);
            app.Plot1Smoothtype.Value = 3;
            app.AmplitudeThresholdLabel_2 = uilabel(app.Plot1PeaksPanel);
            app.AmplitudeThresholdLabel_2.Text = {'Amplitude'; 'Threshold'};
            app.Plot1Ampthreshold = uispinner(app.Plot1PeaksPanel);
            app.Plot1Ampthreshold.Step = 0.1;
            app.Plot1Ampthreshold.Limits = [0 Inf];
            app.Plot1Ampthreshold.ValueChangedFcn = createCallbackFcn(app, @Plot1AmpthresholdValueChanged, true);
            app.Plot1Ampthreshold.Value = 2;
            app.Plot2PeaksPanel = uipanel(app.PeakAnalysisPanel);
            app.Plot2PeaksPanel.Title = 'Plot2 peaks';
            app.sdevLabel_2 = uilabel(app.Plot2PeaksPanel);
            app.sdevLabel_2.Text = 'sdev';
            app.Plot2stdDisp = uieditfield(app.Plot2PeaksPanel, 'numeric');
            app.Plot2stdDisp.Editable = 'off';
            app.SlopeThresholdLabel = uilabel(app.Plot2PeaksPanel);
            app.SlopeThresholdLabel.Text = {'Slope'; ' Threshold'};
            app.Plot2Slopethreshold = uieditfield(app.Plot2PeaksPanel, 'numeric');
            app.Plot2Slopethreshold.Limits = [0 Inf];
            app.Plot2Slopethreshold.ValueChangedFcn = createCallbackFcn(app, @Plot2SlopethresholdValueChanged, true);
            app.Plot2Slopethreshold.Value = 0.0001;
            app.SmoothwidthLabel = uilabel(app.Plot2PeaksPanel);
            app.SmoothwidthLabel.Text = {'Smooth'; 'width'};
            app.Plot2SmoothWidth = uieditfield(app.Plot2PeaksPanel, 'numeric');
            app.Plot2SmoothWidth.Limits = [0 Inf];
            app.Plot2SmoothWidth.ValueChangedFcn = createCallbackFcn(app, @Plot2SmoothWidthValueChanged, true);
            app.Plot2SmoothWidth.Value = 101;
            app.SmoothtypeSpinnerLabel = uilabel(app.Plot2PeaksPanel);
            app.SmoothtypeSpinnerLabel.Text = 'Smooth type';
            app.Plot2Smoothtype = uispinner(app.Plot2PeaksPanel);
            app.Plot2Smoothtype.Limits = [1 3];
            app.Plot2Smoothtype.ValueChangedFcn = createCallbackFcn(app, @Plot2SmoothtypeValueChanged, true);
            app.Plot2Smoothtype.Value = 3;
            app.GroupwidthLabel_2 = uilabel(app.Plot2PeaksPanel);
            app.GroupwidthLabel_2.Text = {'Group'; 'width'};
            app.Plot2GroupWidth = uieditfield(app.Plot2PeaksPanel, 'numeric');
            app.Plot2GroupWidth.Limits = [0 Inf];
            app.Plot2GroupWidth.ValueChangedFcn = createCallbackFcn(app, @Plot2GroupWidthValueChanged, true);
            app.Plot2GroupWidth.Value = 101;
            app.AmplitudeThresholdLabel_3 = uilabel(app.Plot2PeaksPanel);
            app.AmplitudeThresholdLabel_3.Text = {'Amplitude'; 'Threshold'};
            app.Plot2Ampthreshold = uispinner(app.Plot2PeaksPanel);
            app.Plot2Ampthreshold.Step = 0.1;
            app.Plot2Ampthreshold.Limits = [0 Inf];
            app.Plot2Ampthreshold.ValueChangedFcn = createCallbackFcn(app, @Plot2AmpthresholdValueChanged, true);
            app.Plot2Ampthreshold.Value = 2;
            app.ReGrouppeaksButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.ReGrouppeaksButton.ButtonPushedFcn = createCallbackFcn(app, @ReGrouppeaksButtonPushed, true);
            app.ReGrouppeaksButton.Text = 'ReGroup peaks';
            app.GrouppeakswithinLabel = uilabel(app.PeakAnalysisPanel);
            app.GrouppeakswithinLabel.Text = {'Group peaks'; ' within'};
            app.DurationThresholdSpinner = uispinner(app.PeakAnalysisPanel);
            app.DurationThresholdSpinner.Limits = [0 Inf];
            app.DurationThresholdSpinner.ValueDisplayFormat = '%.0f';
            app.DurationThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @DurationThresholdSpinnerValueChanged, true);
            app.DurationThresholdSpinner.Value = 500;
            app.msLabel = uilabel(app.PeakAnalysisPanel);
            app.msLabel.Text = 'ms';
            app.findpeaksButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.findpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @findpeaksButtonPushed, true);
            app.findpeaksButton.Text = 'findpeaks';
            app.AddPlot1PeakButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.AddPlot1PeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot1PeakButtonPushed, true);
            app.AddPlot1PeakButton.Text = 'Add Plot1 Peak';
            app.AddPlot2PeakButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.AddPlot2PeakButton.ButtonPushedFcn = createCallbackFcn(app, @AddPlot2PeakButtonPushed, true);
            app.AddPlot2PeakButton.Text = 'Add Plot2 Peak';
            app.NegativepeaksSwitchLabel = uilabel(app.PeakAnalysisPanel);
            app.NegativepeaksSwitchLabel.Text = 'Negative peaks?';
            app.NegativepeaksSwitch = uiswitch(app.PeakAnalysisPanel, 'slider');
            app.NegativepeaksSwitch.ValueChangedFcn = createCallbackFcn(app, @NegativepeaksSwitchValueChanged, true);
            app.AddspecialPeaksButton = uibutton(app.PeakAnalysisPanel, 'push');
            app.AddspecialPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @AddspecialPeaksButtonPushed, true);
            app.AddspecialPeaksButton.Text = 'Add special Peaks';
            app.PeakDetectionMethodDropDownLabel = uilabel(app.PeakAnalysisPanel);
            app.PeakDetectionMethodDropDownLabel.Text = 'Peak Detection Method';
            app.PeakDetectionMethodDropDown = uidropdown(app.PeakAnalysisPanel);
            app.PeakDetectionMethodDropDown.Items = {'FindPeaks Matlab', 'Gaussian Fitting'};
            app.PeakDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDetectionMethodDropDownValueChanged, true);
            app.PeakDetectionMethodDropDown.Value = 'FindPeaks Matlab';
            app.plot1 = uiaxes(app.FindPeaksTab);
            title(app.plot1, 'Resonance position')
            xlabel(app.plot1, 'Time [s]')
            ylabel(app.plot1, '\Delta\lambda [fm]')
            app.plot2 = uiaxes(app.FindPeaksTab);
            title(app.plot2, 'Full-width-half-maximum')
            xlabel(app.plot2, 'Time [s]')
            ylabel(app.plot2, '\kappa [fm]')
            app.PeaksPlot = uiaxes(app.FindPeaksTab);
            title(app.PeaksPlot, 'Peak Height')
            xlabel(app.PeaksPlot, 'Time [s]')
            ylabel(app.PeaksPlot, 'Amplitude [fm]')
            app.SaveplotsButton = uibutton(app.FindPeaksTab, 'push');
            app.SaveplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotsButtonPushed, true);
            app.SaveplotsButton.Text = 'Save plots';
            app.PlotdetrendeddataSwitchLabel = uilabel(app.FindPeaksTab);
            app.PlotdetrendeddataSwitchLabel.Text = {'Plot detrended '; 'data?'};
            app.PlotdetrendeddataSwitch = uiswitch(app.FindPeaksTab, 'slider');
            app.PlotdetrendeddataSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotdetrendeddataSwitchValueChanged, true);
            app.StepDetectionPanel = uipanel(app.FindPeaksTab);
            app.StepDetectionPanel.Title = 'Step Detection';
            app.AddStepPlot1Button = uibutton(app.StepDetectionPanel, 'push');
            app.AddStepPlot1Button.ButtonPushedFcn = createCallbackFcn(app, @AddStepPlot1ButtonPushed, true);
            app.AddStepPlot1Button.Text = '+Plot 1';
            app.startPointSpinnerLabel = uilabel(app.StepDetectionPanel);
            app.startPointSpinnerLabel.Text = 'startPoint';
            app.startPointSpinner = uispinner(app.StepDetectionPanel);
            app.startPointSpinner.Limits = [0 Inf];
            app.startPointSpinner.ValueChangedFcn = createCallbackFcn(app, @startPointSpinnerValueChanged, true);
            app.startPointSpinner.Value = 100;
            app.rightIntervalSpinnerLabel = uilabel(app.StepDetectionPanel);
            app.rightIntervalSpinnerLabel.Text = 'rightInterval';
            app.rightIntervalSpinner = uispinner(app.StepDetectionPanel);
            app.rightIntervalSpinner.Limits = [0 Inf];
            app.rightIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @rightIntervalSpinnerValueChanged, true);
            app.rightIntervalSpinner.Value = 10;
            app.leftIntervalSpinnerLabel = uilabel(app.StepDetectionPanel);
            app.leftIntervalSpinnerLabel.Text = 'leftInterval';
            app.leftIntervalSpinner = uispinner(app.StepDetectionPanel);
            app.leftIntervalSpinner.Limits = [0 Inf];
            app.leftIntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @leftIntervalSpinnerValueChanged, true);
            app.leftIntervalSpinner.Value = 10;
            app.leftOffsetSpinnerLabel = uilabel(app.StepDetectionPanel);
            app.leftOffsetSpinnerLabel.Text = 'leftOffset';
            app.leftOffsetSpinner = uispinner(app.StepDetectionPanel);
            app.leftOffsetSpinner.Limits = [0 Inf];
            app.leftOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @leftOffsetSpinnerValueChanged, true);
            app.rightOffsetSpinnerLabel = uilabel(app.StepDetectionPanel);
            app.rightOffsetSpinnerLabel.Text = 'rightOffset';
            app.rightOffsetSpinner = uispinner(app.StepDetectionPanel);
            app.rightOffsetSpinner.Limits = [0 Inf];
            app.rightOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @rightOffsetSpinnerValueChanged, true);
            app.Plot1heightfmEditFieldLabel = uilabel(app.StepDetectionPanel);
            app.Plot1heightfmEditFieldLabel.Text = 'Plot1 height [fm]';
            app.plot1heightfmEditField = uieditfield(app.StepDetectionPanel, 'numeric');
            app.plot1heightfmEditField.Editable = 'off';
            app.Plot2heightfmEditFieldLabel = uilabel(app.StepDetectionPanel);
            app.Plot2heightfmEditFieldLabel.Text = 'Plot2 height [fm]';
            app.plot2heightfmEditField = uieditfield(app.StepDetectionPanel, 'numeric');
            app.plot2heightfmEditField.Editable = 'off';
            app.AddStepPlot2Button = uibutton(app.StepDetectionPanel, 'push');
            app.AddStepPlot2Button.ButtonPushedFcn = createCallbackFcn(app, @AddStepPlot2ButtonPushed, true);
            app.AddStepPlot2Button.Text = '+Plot 2';
            app.WinsizeSpinnerLabel = uilabel(app.FindPeaksTab);
            app.WinsizeSpinnerLabel.Text = 'Win size';
            app.WinsizeSpinner = uispinner(app.FindPeaksTab);
            app.WinsizeSpinner.Step = 2;
            app.WinsizeSpinner.Limits = [5 Inf];
            app.WinsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @WinsizeSpinnerValueChanged, true);
            app.WinsizeSpinner.Value = 101;
            app.XStartSpinnerLabel = uilabel(app.FindPeaksTab);
            app.XStartSpinnerLabel.Text = 'XStart';
            app.XStartSpinner = uispinner(app.FindPeaksTab);
            app.XStartSpinner.Step = 0.1;
            app.XStartSpinner.Limits = [0 Inf];
            app.XStartSpinner.ValueChangedFcn = createCallbackFcn(app, @XStartSpinnerValueChanged, true);
            app.XEndSpinnerLabel = uilabel(app.FindPeaksTab);
            app.XEndSpinnerLabel.Text = 'XEnd';
            app.XEndSpinner = uispinner(app.FindPeaksTab);
            app.XEndSpinner.Step = 0.1;
            app.XEndSpinner.Limits = [0 Inf];
            app.XEndSpinner.ValueChangedFcn = createCallbackFcn(app, @XEndSpinnerValueChanged, true);
            app.XEndSpinner.Value = 20;
            app.WidthPlot = uiaxes(app.FindPeaksTab);
            title(app.WidthPlot, 'Peak Duration')
            xlabel(app.WidthPlot, 'Time [s]')
            ylabel(app.WidthPlot, 'Duration [s]')
            app.stepheight = uiaxes(app.FindPeaksTab);
            title(app.stepheight, 'Step Height')
            xlabel(app.stepheight, 'Time [s]')
            ylabel(app.stepheight, 'Amplitude [fm]')
            app.PlotoptionsPanel = uipanel(app.FindPeaksTab);
            app.PlotoptionsPanel.Title = 'Plot options';
            app.panPlots = uibutton(app.PlotoptionsPanel, 'push');
            app.panPlots.ButtonPushedFcn = createCallbackFcn(app, @panPlotsButtonPushed, true);
            app.panPlots.Text = 'P';
            app.resetPlots = uibutton(app.PlotoptionsPanel, 'push');
            app.resetPlots.ButtonPushedFcn = createCallbackFcn(app, @resetPlotsButtonPushed, true);
            app.resetPlots.Text = 'R';
            app.zoomPlots = uibutton(app.PlotoptionsPanel, 'push');
            app.zoomPlots.ButtonPushedFcn = createCallbackFcn(app, @zoomPlotsButtonPushed, true);
            app.zoomPlots.Text = 'Z';
            app.getPointsPlots = uibutton(app.PlotoptionsPanel, 'push');
            app.getPointsPlots.ButtonPushedFcn = createCallbackFcn(app, @getPointsPlotsButtonPushed, true);
            app.getPointsPlots.Enable = 'off';
            app.getPointsPlots.Text = 'G';
            app.RedetrendButton = uibutton(app.FindPeaksTab, 'push');
            app.RedetrendButton.ButtonPushedFcn = createCallbackFcn(app, @RedetrendButtonPushed, true);
            app.RedetrendButton.Text = 'Redetrend';
            app.ChannelDropDownLabel = uilabel(app.FindPeaksTab);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.FindPeaksTab);
            app.ChannelDropDown.Items = {'ai0', 'ai1'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Value = 'ai0';
            app.ChannelDropDown_2Label = uilabel(app.FindPeaksTab);
            app.ChannelDropDown_2Label.Enable = 'off';
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.FindPeaksTab);
            app.ChannelDropDown_2.Items = {'ai0', 'ai1'};
            app.ChannelDropDown_2.Enable = 'off';
            app.ChannelDropDown_2.Value = 'ai0';
            app.stepheight_2 = uiaxes(app.FindPeaksTab);
            title(app.stepheight_2, 'Step Height')
            xlabel(app.stepheight_2, 'Time [s]')
            ylabel(app.stepheight_2, 'Duration [s]')
            app.Panel = uipanel(app.FindPeaksTab);
            app.Panel.Title = 'Panel';
            app.StartTimeSpinnerLabel = uilabel(app.Panel);
            app.StartTimeSpinnerLabel.Text = 'Start Time';
            app.StartTimeSpinner = uispinner(app.Panel);
            app.StartTimeSpinner.Limits = [0 Inf];
            app.StartTimeSpinner.ValueDisplayFormat = '%.0f';
            app.StartTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @StartTimeSpinnerValueChanged, true);
            app.StartTimeSpinner.Value = 1;
            app.PlotdurationSpinnerLabel = uilabel(app.Panel);
            app.PlotdurationSpinnerLabel.Text = 'Plot duration';
            app.PlotdurationSpinner = uispinner(app.Panel);
            app.PlotdurationSpinner.Limits = [0 Inf];
            app.PlotdurationSpinner.ValueDisplayFormat = '%.0f';
            app.PlotdurationSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotdurationSpinnerValueChanged, true);
            app.PlotdurationSpinner.Value = 20;
            app.StdoverpointsSpinnerLabel = uilabel(app.Panel);
            app.StdoverpointsSpinnerLabel.Text = 'Std over points';
            app.nStdSpinner = uispinner(app.Panel);
            app.nStdSpinner.Limits = [1 Inf];
            app.nStdSpinner.ValueChangedFcn = createCallbackFcn(app, @nStdSpinnerValueChanged, true);
            app.nStdSpinner.Value = 10;
            app.ModenumberDropDownLabel = uilabel(app.Panel);
            app.ModenumberDropDownLabel.Text = 'Mode number';
            app.ModenumberDropDown = uidropdown(app.Panel);
            app.ModenumberDropDown.Items = {'1'};
            app.ModenumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ModenumberDropDownValueChanged, true);
            app.ModenumberDropDown.Value = '1';
            app.AnalysePeaksTab = uitab(app.TabGroup);
            app.AnalysePeaksTab.Title = 'Analyse Peaks';
            app.onrateplot = uiaxes(app.AnalysePeaksTab);
            title(app.onrateplot, 'On Rate')
            xlabel(app.onrateplot, '\Deltat [s]')
            ylabel(app.onrateplot, 'P(\Deltat)')
            app.PlotratesButton = uibutton(app.AnalysePeaksTab, 'push');
            app.PlotratesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotratesButtonPushed, true);
            app.PlotratesButton.Text = 'Plot rates';
            app.SaverateplotButton = uibutton(app.AnalysePeaksTab, 'push');
            app.SaverateplotButton.ButtonPushedFcn = createCallbackFcn(app, @SaverateplotButtonPushed, true);
            app.SaverateplotButton.Text = 'Save rate plot';
            app.LoadpeakdataButton = uibutton(app.AnalysePeaksTab, 'push');
            app.LoadpeakdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpeakdataButtonPushed, true);
            app.LoadpeakdataButton.Text = 'Load peak data';
            app.sLabel = uilabel(app.AnalysePeaksTab);
            app.sLabel.Text = '1/s';
            app.RateEditFieldLabel = uilabel(app.AnalysePeaksTab);
            app.RateEditFieldLabel.Text = 'Rate';
            app.OnRateDispField = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.OnRateDispField.Editable = 'off';
            app.ConcEditFieldLabel = uilabel(app.AnalysePeaksTab);
            app.ConcEditFieldLabel.Text = 'Conc.';
            app.ConcEditField = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.uMLabel = uilabel(app.AnalysePeaksTab);
            app.uMLabel.Text = 'uM';
            app.AddConctoplotButton = uibutton(app.AnalysePeaksTab, 'push');
            app.AddConctoplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddConctoplotButtonPushed, true);
            app.AddConctoplotButton.Text = 'Add Conc. to plot';
            app.FittorateButton = uibutton(app.AnalysePeaksTab, 'push');
            app.FittorateButton.ButtonPushedFcn = createCallbackFcn(app, @FittorateButtonPushed, true);
            app.FittorateButton.Text = 'Fit to rate ';
            app.offrateplot = uiaxes(app.AnalysePeaksTab);
            title(app.offrateplot, 'Off Rate')
            xlabel(app.offrateplot, '\Deltat [s]')
            ylabel(app.offrateplot, 'P(\Deltat)')
            app.FilepathTextArea_2Label = uilabel(app.AnalysePeaksTab);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.peakdataFilePath = uitextarea(app.AnalysePeaksTab);
            app.peakdataFilePath.Editable = 'off';
            app.OnrateLabel = uilabel(app.AnalysePeaksTab);
            app.OnrateLabel.Text = 'On rate';
            app.onrateDisp = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.onrateDisp.Editable = 'off';
            app.MsLabel = uilabel(app.AnalysePeaksTab);
            app.MsLabel.Text = '1/(M.s)';
            app.sLabel_3 = uilabel(app.AnalysePeaksTab);
            app.sLabel_3.Text = '1/s';
            app.OffRateDispEditFieldLabel = uilabel(app.AnalysePeaksTab);
            app.OffRateDispEditFieldLabel.Text = 'OffRateDisp';
            app.OffRateDispField = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.OffRateDispField.Editable = 'off';
            app.RatefrommeanLabel = uilabel(app.AnalysePeaksTab);
            app.RatefrommeanLabel.Text = 'Rate from mean';
            app.OnRateFromMean = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.OnRateFromMean.Editable = 'off';
            app.RatefrommeanLabel_2 = uilabel(app.AnalysePeaksTab);
            app.RatefrommeanLabel_2.Text = 'Rate from mean';
            app.OffRateFromMean = uieditfield(app.AnalysePeaksTab, 'numeric');
            app.OffRateFromMean.Editable = 'off';
            app.concdependence_2 = uiaxes(app.AnalysePeaksTab);
            title(app.concdependence_2, 'Conc. dependence')
            xlabel(app.concdependence_2, 'conc. [uM]')
            ylabel(app.concdependence_2, 'rate [1/s]')
            app.Tree = uitree(app.AnalysePeaksTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.PeaksNode = uitreenode(app.Tree);
            app.PeaksNode.Text = 'Peaks';
            app.UITable = uitable(app.AnalysePeaksTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.msLabel_2 = uilabel(app.AnalysePeaksTab);
            app.msLabel_2.Text = 'ms';
            app.GrouppeakswithinLabel_2 = uilabel(app.AnalysePeaksTab);
            app.GrouppeakswithinLabel_2.Text = {'Group peaks'; ' within'};
            app.DurationThresholdSpinner_2 = uispinner(app.AnalysePeaksTab);
            app.DurationThresholdSpinner_2.Limits = [0 Inf];
            app.DurationThresholdSpinner_2.ValueDisplayFormat = '%.0f';
            app.DurationThresholdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DurationThresholdSpinnerValueChanged, true);
            app.DurationThresholdSpinner_2.Value = 500;
            app.ReGrouppeaksButton_2 = uibutton(app.AnalysePeaksTab, 'push');
            app.ReGrouppeaksButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReGrouppeaksButton_2Pushed, true);
            app.ReGrouppeaksButton_2.Enable = 'off';
            app.ReGrouppeaksButton_2.Text = 'ReGroup peaks';
            app.AnalysisPlotsTab = uitab(app.TabGroup);
            app.AnalysisPlotsTab.Title = 'Analysis Plots';
            app.histheights = uiaxes(app.AnalysisPlotsTab);
            title(app.histheights, 'Histogram of heights')
            xlabel(app.histheights, 'h [fm]')
            ylabel(app.histheights, 'P(h)')
            app.PlotHeightsButton = uibutton(app.AnalysisPlotsTab, 'push');
            app.PlotHeightsButton.Text = 'Plot Heights';
            app.fmLabel = uilabel(app.AnalysisPlotsTab);
            app.fmLabel.Text = 'fm';
            app.MeanheightEditFieldLabel = uilabel(app.AnalysisPlotsTab);
            app.MeanheightEditFieldLabel.Text = 'Mean height';
            app.MeanheightDispField = uieditfield(app.AnalysisPlotsTab, 'numeric');
            app.MeanheightDispField.Editable = 'off';
            % Show the figure after all components are created
            app.DataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAnalysisUIFigure)
        end
    end
end